classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        Panel_4      matlab.ui.container.Panel
        GridLayout5  matlab.ui.container.GridLayout
        GridLayout6  matlab.ui.container.GridLayout
        TextArea     matlab.ui.control.TextArea
        Label_4      matlab.ui.control.Label
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
        Panel_3      matlab.ui.container.Panel
        GridLayout3  matlab.ui.container.GridLayout
        UITable2     matlab.ui.control.Table
        UIAxes2      matlab.ui.control.UIAxes
        Panel_2      matlab.ui.container.Panel
        GridLayout2  matlab.ui.container.GridLayout
        UITable      matlab.ui.control.Table
        UIAxes       matlab.ui.control.UIAxes
        Panel        matlab.ui.container.Panel
        GridLayout4  matlab.ui.container.GridLayout
        EditField_3  matlab.ui.control.NumericEditField
        Label_3      matlab.ui.control.Label
        EditField_2  matlab.ui.control.NumericEditField
        Label_2      matlab.ui.control.Label
        EditField    matlab.ui.control.NumericEditField
        Label        matlab.ui.control.Label
        XEditField   matlab.ui.control.EditField
        XLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        info % 统计信息
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.info = '';
            app.TextArea.Value = app.info;            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            try
            bar = waitbar(0.5,'计算中，根据积分范围和精度可能耗时不同，请耐心等待...');

            T2kArray = TrapezoidalRecursion(app.XEditField.Value,app.EditField.Value, ...
                app.EditField_2.Value,app.EditField_3.Value);

            % 更新表格
            app.UITable.ColumnName = {'迭代次数','积分结果','精度'};
            app.UITable.ColumnFormat = {'long','long','long'};
            app.UITable.Data = T2kArray;
            plot(app.UIAxes,T2kArray(:,1),T2kArray(:,2));

            % 统计信息
            infoT = ['使用梯形迭代法迭代了' num2str(size(T2kArray,1) - 1) '次' '，最终积分结果为' num2str(T2kArray(size(T2kArray,1),2),15)];
            app.info = [app.info newline infoT];
            app.TextArea.Value = app.info;
            close(bar);
            msgbox(['计算结束:' infoT]);          
            catch ErrorInfo
                errordlg(ErrorInfo.message,'发生了错误');                
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            try
            bar = waitbar(0.5,'计算中，根据积分范围和精度可能耗时不同，请耐心等待...');
            TmkArray = RombergIntegral(app.XEditField.Value,app.EditField.Value, ...
                app.EditField_2.Value,app.EditField_3.Value);            
        
            % 重新排列结果并更新表格
            k = max(TmkArray(:,1)); % 迭代次数
            m = max(TmkArray(:,2)); % 加速次数   
            column_name = cell(1,m);
            column_format = cell(1,m);
            for i = 0 : m
                column_name{1,i + 1} = ['T' num2str(i) '(k)'];
                column_format{1,i + 1} = 'long';
            end
            app.UITable2.ColumnName = column_name;
            app.UITable2.ColumnFormat = column_format;
            TmkArray_reshape = zeros(k + 1,m + 1);
            for row = 0 : k
                for column = 0 : k
                    if(row >= column)
                        row_index = ((1 + row) * row) / 2 + 1 + column;                    
                        TmkArray_reshape(row + 1,column + 1) = TmkArray(row_index,3);
                    end
                end
            end
            app.UITable2.Data = TmkArray_reshape;

            % 画图
            accelerate_result = zeros(k + 1,1);
            for j = 0:m
                index = ((1 + j) * j) / 2 + 1 + j;
                accelerate_result(j + 1,1) = TmkArray(index,3);               
            end
            plot(app.UIAxes2,linspace(0,m,m+1),accelerate_result);

            % 统计信息            
            infoR = ['使用龙贝格算法迭代了' num2str(k) '次，加速了' num2str(m) '次，最终积分结果为' num2str(TmkArray(size(TmkArray,1),3),15)];
            app.info = [app.info newline infoR];
            app.TextArea.Value = app.info;
            close(bar);
            msgbox(infoR);            
            catch ErrorInfo
                errordlg(ErrorInfo.message,'发生了错误');
                close(bar);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '梯形迭代与龙贝格算法';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = '参数输入';
            app.GridLayout4 = uigridlayout(app.Panel);
            app.XLabel = uilabel(app.GridLayout4);
            app.XLabel.Text = {'被积函数(必须以X作为自变量)'; ''};
            app.XEditField = uieditfield(app.GridLayout4, 'text');
            app.XEditField.Tooltip = {'必须以x作为自变量，否则不识别'};
            app.XEditField.Value = 'exp(x)';
            app.Label = uilabel(app.GridLayout4);
            app.Label.Text = '积分下限';
            app.EditField = uieditfield(app.GridLayout4, 'numeric');
            app.Label_2 = uilabel(app.GridLayout4);
            app.Label_2.Text = '积分上限';
            app.EditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_2.Value = 1;
            app.Label_3 = uilabel(app.GridLayout4);
            app.Label_3.Text = '最小精度';
            app.EditField_3 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_3.Limits = [1e-14 0.1];
            app.EditField_3.Tooltip = {'精度范围1e-8~1e-1'};
            app.EditField_3.Value = 1e-06;
            app.Panel_2 = uipanel(app.GridLayout);
            app.Panel_2.Title = '梯形迭代结果';
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, '梯形迭代积分结果收敛情况')
            xlabel(app.UIAxes, '迭代次数')
            ylabel(app.UIAxes, '积分值')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Panel_3 = uipanel(app.GridLayout);
            app.Panel_3.Title = '龙贝格算法结果';
            app.GridLayout3 = uigridlayout(app.Panel_3);
            app.UIAxes2 = uiaxes(app.GridLayout3);
            title(app.UIAxes2, '龙贝格算法积分收敛情况')
            xlabel(app.UIAxes2, '加速次数')
            ylabel(app.UIAxes2, '积分值')
            zlabel(app.UIAxes2, 'Z')
            app.UITable2 = uitable(app.GridLayout3);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.Panel_4 = uipanel(app.GridLayout);
            app.Panel_4.Title = '操作与统计信息';
            app.GridLayout5 = uigridlayout(app.Panel_4);
            app.Button = uibutton(app.GridLayout5, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '进行梯形迭代';
            app.Button_2 = uibutton(app.GridLayout5, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '使用龙贝格算法';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.Label_4 = uilabel(app.GridLayout6);
            app.Label_4.Text = '统计信息';
            app.TextArea = uitextarea(app.GridLayout6);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end